classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrumAnalyzerUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        FileofdataEditFieldLabel  matlab.ui.control.Label
        FileofdataEditField       matlab.ui.control.EditField
        LOADButton                matlab.ui.control.Button
        EXITButton                matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
        SIDESwitch                matlab.ui.control.ToggleSwitch
        ScaleLabel                matlab.ui.control.Label
        ScaleKnob                 matlab.ui.control.Knob
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        Switch                    matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data;
        t;
        s;
        fs;
        N;
        f1;
        f2;
        df;
        currentf1;
        currentf2;
        S1;
        S2;
        switcher;
        side;
        scale;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.switcher = app.Switch.Value;
            app.side = app.SIDESwitch.Value;
            app.scale = app.ScaleKnob.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpectrumAnalyzerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {588, 588};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            file = app.FileofdataEditField.Value;
            app.Data = load(file);
            app.t = app.Data.t;
            app.s = app.Data.s;
            app.fs = 1/(app.t(2)-app.t(1));
            app.N = length(app.t);
            app.f1 = linspace(0,app.fs,app.N);
            app.f2 = linspace(-app.fs/2,app.fs/2,app.N);
            app.df = app.f1(2)-app.f1(1);
            app.currentf1 = app.fs;
            app.currentf2 = app.fs/2;
            app.S1 = fft(app.s)/app.N;
            app.S2 = fftshift(app.S1);
            app.UIAxes.XLim = [app.t(1) app.t(end)];
            plot(app.UIAxes,app.t,app.s,'-k','LineWidth',1.5);
            app.UIAxes.XLabel.String = 't, c';
            app.UIAxes.YLabel.String = 's(t), B';
            app.Switch.Enable = 'on';
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close all force;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.switcher = app.Switch.Value;
            if strcmp(convertCharsToStrings(app.switcher),"Signal")
                app.UIAxes.XLim = [app.t(1) app.t(end)];
                plot(app.UIAxes,app.t,app.s,'-k','LineWidth',1.5);
                app.UIAxes.XLabel.String = 't, c';
                app.UIAxes.YLabel.String = 's(t), B';
                app.SIDESwitch.Enable = 'off';
                app.ScaleKnob.Enable = 'off';
                app.ScaleLabel.Enable = 'off';
            elseif strcmp(convertCharsToStrings(app.side),"One-sided")
                app.UIAxes.XLim = [0 app.currentf1];
                plot(app.UIAxes,app.f1,abs(app.S1),'-k','LineWidth',1.5);
                app.UIAxes.XLabel.String = 'f, Hz';
                app.UIAxes.YLabel.String = '|S(f)|, B\cdotc';
                app.SIDESwitch.Enable = 'on';
                app.ScaleKnob.Enable = 'on';
                app.ScaleLabel.Enable = 'on';
            else
                app.UIAxes.XLim = [-app.currentf2 app.currentf2];
                plot(app.UIAxes,app.f2,abs(app.S2),'-k','LineWidth',1.5);
                app.UIAxes.XLabel.String = 'f, Hz';
                app.UIAxes.YLabel.String = '|S(f)|, B\cdotc';
                app.SIDESwitch.Enable = 'on';
                app.ScaleKnob.Enable = 'on';
                app.ScaleLabel.Enable = 'on';
            end
        end

        % Value changed function: SIDESwitch
        function SIDESwitchValueChanged(app, event)
            app.side = app.SIDESwitch.Value;
            if strcmp(convertCharsToStrings(app.side),"One-sided")
                app.UIAxes.XLim = [0 app.currentf1];
                plot(app.UIAxes,app.f1,abs(app.S1),'-k','LineWidth',1.5);
                app.UIAxes.XLabel.String = 'f, Hz';
                app.UIAxes.YLabel.String = '|S(f)|, B\cdotc';
            else
                app.UIAxes.XLim = [-app.currentf2 app.currentf2];
                plot(app.UIAxes,app.f2,abs(app.S2),'-k','LineWidth',1.5);
                app.UIAxes.XLabel.String = 'f, Hz';
                app.UIAxes.YLabel.String = '|S(f)|, B\cdotc';
            end
        end

        % Value changing function: ScaleKnob
        function ScaleKnobValueChanging(app, event)
            app.scale = event.Value;
            app.ScaleLabel.Text = [string(convertCharsToStrings(round(app.scale)))+"%","Scale"];
            if app.scale == 100
                app.currentf1 = app.fs;
                app.currentf2 = app.currentf1/2;
            else
                app.currentf1 = app.fs/100*convert2db(app.scale);
                app.currentf2 = app.currentf1/2;
                if app.currentf1 < app.df
                    app.currentf1 = app.df;
                end
                if app.currentf2 < app.df
                    app.currentf2 = app.df;
                end
            end
            if strcmp(convertCharsToStrings(app.side),"One-sided")
                app.UIAxes.XLim = [0 app.currentf1];
            else
                app.UIAxes.XLim = [-app.currentf2 app.currentf2];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrumAnalyzerUIFigure = uifigure('Visible', 'off');
            app.SpectrumAnalyzerUIFigure.Name = 'Spectrum Analyzer';
            app.SpectrumAnalyzerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpectrumAnalyzerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileofdataEditFieldLabel = uilabel(app.LeftPanel);
            app.FileofdataEditFieldLabel.Text = 'File of data';
            app.FileofdataEditField = uieditfield(app.LeftPanel, 'text');
            app.LOADButton = uibutton(app.LeftPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.EXITButton = uibutton(app.LeftPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.SIDESwitch = uiswitch(app.LeftPanel, 'toggle');
            app.SIDESwitch.Items = {'One-sided', 'Two-sided'};
            app.SIDESwitch.ValueChangedFcn = createCallbackFcn(app, @SIDESwitchValueChanged, true);
            app.SIDESwitch.Enable = 'off';
            app.SIDESwitch.Value = 'One-sided';
            app.ScaleLabel = uilabel(app.LeftPanel);
            app.ScaleLabel.Enable = 'off';
            app.ScaleLabel.Text = {'100%'; 'Scale'};
            app.ScaleKnob = uiknob(app.LeftPanel, 'continuous');
            app.ScaleKnob.ValueChangingFcn = createCallbackFcn(app, @ScaleKnobValueChanging, true);
            app.ScaleKnob.Enable = 'off';
            app.ScaleKnob.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.Items = {'Signal', 'Spectrum'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'Signal';
            % Show the figure after all components are created
            app.SpectrumAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrumAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrumAnalyzerUIFigure)
        end
    end
end